classdef multiTestCaseSeq < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MultiTestCaseSequenceUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        ListBoxLabel                   matlab.ui.control.Label
        ListBox                        matlab.ui.control.ListBox
        Panel_5                        matlab.ui.container.Panel
        MoveUpButton                   matlab.ui.control.Button
        MoveDownButton                 matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
        CopyButton                     matlab.ui.control.Button
        RenameButton                   matlab.ui.control.Button
        Panel_2                        matlab.ui.container.Panel
        EditButton                     matlab.ui.control.Button
        SetActiveButton                matlab.ui.control.Button
        UpdatePortsButton              matlab.ui.control.Button
    end


    properties (Access = public)
        testCaseBlks % Description
    end

    properties (Access = private)
        testSeqBlk % Description
    end

    methods (Access = private)
    
        function updateMultiTestCaseBlock(app)
            
            testCaseListNames = cellfun(@(x) regexprep(x,'*',''), ...
                app.ListBox.Items,'UniformOutput',false);
            multiTestSeqBlkMgr(app.testSeqBlk, ...
                'updateExecOrder', testCaseListNames);
            
        end
        
        function setTestCaseBlks(app)
            
            app.testCaseBlks = multiTestSeqBlkMgr(app.testSeqBlk, ...
                'getTestCaseBlocks');

        end
        
        function testCaseNum = getTestCaseNum(app)
            
            testCaseNum = find(strcmp(app.ListBox.Value, ...
                app.ListBox.Items));
            
        end
        
        function uniqueName = getUniqueTestCaseName(app, newName)

            uniqueName = newName;
            attempt = 0;
            while true
                attempt = attempt + 1;
                if isempty(find(strcmp(uniqueName,app.ListBox.Items), 1))
                    return;
                elseif attempt==100
                    return;
                end
                uniqueName = [newName '_' num2str(attempt)];
            end
            
        end
        
        function setButtonEnables(app)
            
            if isempty(app.testCaseBlks)
                createEnable = 'on';
                createEnableNot = 'off';
            else
                createEnable = 'off';
                createEnableNot = 'on';
            end
            app.CreateButton.Enable = createEnable;
            app.MoveUpButton.Enable = createEnableNot;
            app.MoveDownButton.Enable = createEnableNot;
            app.DeleteButton.Enable = createEnableNot;
            app.CopyButton.Enable = createEnableNot;             
            app.EditButton.Enable = createEnableNot;             
            app.SetActiveButton.Enable = createEnableNot;        
            app.UpdatePortsButton.Enable = 'off';
            app.RenameButton.Enable = createEnableNot;

        end
                
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.testSeqBlk = gcb;
            setTestCaseBlks(app);
            setButtonEnables(app);
            if isempty(app.testCaseBlks)
                app.ListBox.Items = {''};
                app.ListBox.Value = '';
            else
                testCaseNames = get_param(app.testCaseBlks,'Name');
                activeBlock = multiTestSeqBlkMgr(app.testSeqBlk, ...
                    'getActiveVariantBlock');
                activeTestCase = get_param(activeBlock,'Name');
                activeTestNum = find(ismember(testCaseNames,activeTestCase),1);
                if ~isempty(activeTestNum)
                    testCaseNames{activeTestNum} = [testCaseNames{activeTestNum} '*'];
                end
                app.ListBox.Items = testCaseNames;
                app.ListBox.Value = char(testCaseNames(activeTestNum));
            end
            
        end

        % Button pushed function: SetActiveButton
        function SetActiveButtonPushed(app, event)
            
            multiTestSeqBlkMgr(app.testSeqBlk,'setActiveVariantBlock', ...
                [app.testSeqBlk '/' app.ListBox.Value]);
            
            testCaseNum = getTestCaseNum(app);
            app.ListBox.Items = cellfun(@(x) regexprep(x,'*',''), ...
                app.ListBox.Items,'UniformOutput',false);
            app.ListBox.Items{testCaseNum} = [app.ListBox.Items{testCaseNum} '*'];
            app.ListBox.Value = app.ListBox.Items{testCaseNum};
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            open_system([app.testSeqBlk '/' regexprep(app.ListBox.Value,'\*','')]);
        end

        % Button pushed function: MoveUpButton
        function MoveUpButtonPushed(app, event)
            ndxLogic = strcmp(app.ListBox.Value, app.ListBox.Items);
            ndx = find(ndxLogic);
            if ndx>=2 
                app.ListBox.Items = [app.ListBox.Items(~ndxLogic(1:ndx-2)) ...
                    app.ListBox.Items(ndx) ...
                    app.ListBox.Items([false(1,ndx-2) ~ndxLogic(ndx-1:end)])];
                updateMultiTestCaseBlock(app);
            end
        end

        % Button pushed function: MoveDownButton
        function MoveDownButtonPushed(app, event)
            ndxLogic = strcmp(app.ListBox.Value, app.ListBox.Items);
            ndx = find(ndxLogic);
            if ndx<numel(app.ListBox.Items) 
                app.ListBox.Items = [app.ListBox.Items(~ndxLogic(1:ndx+1)) ...
                    app.ListBox.Items(ndx) ...
                    app.ListBox.Items([false(1,ndx+1) ~ndxLogic(ndx+2:end)])];
                updateMultiTestCaseBlock(app);
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            if numel(app.ListBox.Items)==1
                return;
            end
            ndx = getTestCaseNum(app);
            app.ListBox.Items = [app.ListBox.Items(1:ndx-1) app.ListBox.Items(ndx+1:end)];
            app.ListBox.Value = app.ListBox.Items{min(ndx,numel(app.ListBox.Items))};
            newList = cellfun(@(x) regexprep(x,'\*$',''),app.ListBox.Items, ...
                'UniformOutput',false); 
            multiTestSeqBlkMgr(app.testSeqBlk,'deleteTest',newList);
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            % select test sequence block to build variant
            uialert(app.MultiTestCaseSequenceUIFigure, ...
                ['Select block to include in manager...' newline ...
                'press "OK" when selected.'], ...
                'Multi Test Case Sequence',...
                'Icon','info','Modal',true);
            return;
            
            % populate variant subsystem with selected test sequence
            setTestCaseBlks(app);
            setButtonEnables(app);
            if isempty(app.testCaseBlks)
                multiTestSeqBlkMgr(app.testSeqBlk,'create');                
                
            end
        end

        % Button pushed function: CopyButton
        function CopyButtonPushed(app, event)
 
            ndx = getTestCaseNum(app);
            uniqueName = getUniqueTestCaseName(app, ...
                char(app.ListBox.Items(ndx)));
            app.ListBox.Items = [app.ListBox.Items(1:ndx) ...
                {uniqueName} app.ListBox.Items(ndx+1:end)];
            newList = cellfun(@(x) regexprep(x,'\*$',''),app.ListBox.Items, ...
                'UniformOutput',false); 
            multiTestSeqBlkMgr(app.testSeqBlk,'copyTest', ...
                newList,app.ListBox.Value);
            
        end

        % Button pushed function: UpdatePortsButton
        function UpdatePortsButtonPushed(app, event)
            multiTestSeqBlkMgr(app.testSeqBlk, 'updatePorts');
        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)

            activeTest = ~isempty(regexp(app.ListBox.Value,'\*$','once'));
            testCaseNum = getTestCaseNum(app);
            newName = inputdlg('Enter new name...', ...
                'Rename Test Case', [1 50], {app.ListBox.Value});
            if isempty(newName)
                return;
            end
            
            multiTestSeqBlkMgr(app.testSeqBlk,'renameTest', ...
                app.ListBox.Value, char(newName));
            
            if activeTest
                newName = [newName '*'];
            end
            app.ListBox.Items(testCaseNum) = newName;
            app.ListBox.Value = app.ListBox.Items{testCaseNum};

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MultiTestCaseSequenceUIFigure = uifigure;
            app.MultiTestCaseSequenceUIFigure.Name = 'Multi Test Case Sequence';
            setAutoResize(app, app.MultiTestCaseSequenceUIFigure, true)
            app.Panel = uipanel(app.MultiTestCaseSequenceUIFigure);
            app.ListBoxLabel = uilabel(app.Panel);
            app.ListBoxLabel.Text = '';
            app.ListBox = uilistbox(app.Panel);
            app.Panel_5 = uipanel(app.MultiTestCaseSequenceUIFigure);
            app.MoveUpButton = uibutton(app.Panel_5, 'push');
            app.MoveUpButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpButtonPushed, true);
            app.MoveUpButton.Text = 'Move Up';
            app.MoveDownButton = uibutton(app.Panel_5, 'push');
            app.MoveDownButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownButtonPushed, true);
            app.MoveDownButton.Text = 'Move Down';
            app.Panel_3 = uipanel(app.MultiTestCaseSequenceUIFigure);
            app.DeleteButton = uibutton(app.Panel_3, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.CreateButton = uibutton(app.Panel_3, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CopyButton = uibutton(app.Panel_3, 'push');
            app.CopyButton.ButtonPushedFcn = createCallbackFcn(app, @CopyButtonPushed, true);
            app.CopyButton.Text = 'Copy';
            app.RenameButton = uibutton(app.Panel_3, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
            app.Panel_2 = uipanel(app.MultiTestCaseSequenceUIFigure);
            app.EditButton = uibutton(app.Panel_2, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.SetActiveButton = uibutton(app.Panel_2, 'push');
            app.SetActiveButton.ButtonPushedFcn = createCallbackFcn(app, @SetActiveButtonPushed, true);
            app.SetActiveButton.Text = 'Set Active';
            app.UpdatePortsButton = uibutton(app.Panel_2, 'push');
            app.UpdatePortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortsButtonPushed, true);
            app.UpdatePortsButton.Text = 'Update Ports';
        end
    end

    methods (Access = public)

        % Construct app
        function app = multiTestCaseSeq()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MultiTestCaseSequenceUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MultiTestCaseSequenceUIFigure)
        end
    end
end